classdef decApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StegonographyToolkitDecoderUIFigure  matlab.ui.Figure
        BrowseButton               matlab.ui.control.Button
        FileEditFieldLabel         matlab.ui.control.Label
        FileEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel     matlab.ui.control.Label
        PasswordEditField          matlab.ui.control.EditField
        DecodedTextEditFieldLabel  matlab.ui.control.Label
        DecodedTextEditField       matlab.ui.control.EditField
        DecodeButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.FileEditField.Value = uigetfile('*.png')            
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            value = app.FileEditField.Value;            
        end

        % Value changed function: DecodedTextEditField
        function DecodedTextEditFieldValueChanged(app, event)
            value = app.DecodedTextEditField.Value;
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            if (app.PasswordEditField.Value=="1337")
                stenoImg = imread(app.FileEditField.Value);
                message = decode(stenoImg);
                app.DecodedTextEditField.Value = message;
                confBox = msgbox('Decoded successfully.')
            else
                erBox = msgbox('Invalid Password','Authorization Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StegonographyToolkitDecoderUIFigure = uifigure('Visible', 'off');
            app.StegonographyToolkitDecoderUIFigure.Name = 'Stegonography Toolkit: Decoder';
            app.BrowseButton = uibutton(app.StegonographyToolkitDecoderUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileEditFieldLabel = uilabel(app.StegonographyToolkitDecoderUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.StegonographyToolkitDecoderUIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Tag = 'select1';
            app.FileEditField.Editable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.StegonographyToolkitDecoderUIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.StegonographyToolkitDecoderUIFigure, 'text');
            app.PasswordEditField.Tag = 'pass1';
            app.PasswordEditField.Tooltip = {'Encode Password: 9001'};
            app.DecodedTextEditFieldLabel = uilabel(app.StegonographyToolkitDecoderUIFigure);
            app.DecodedTextEditFieldLabel.Text = 'Decoded Text';
            app.DecodedTextEditField = uieditfield(app.StegonographyToolkitDecoderUIFigure, 'text');
            app.DecodedTextEditField.ValueChangedFcn = createCallbackFcn(app, @DecodedTextEditFieldValueChanged, true);
            app.DecodedTextEditField.Tag = 'enc1';
            app.DecodedTextEditField.Editable = 'off';
            app.DecodeButton = uibutton(app.StegonographyToolkitDecoderUIFigure, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Tooltip = {'Decode Password: 1337'};
            app.DecodeButton.Text = 'Decode';
            % Show the figure after all components are created
            app.StegonographyToolkitDecoderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = decApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StegonographyToolkitDecoderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StegonographyToolkitDecoderUIFigure)
        end
    end
end