classdef encApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StegonographyToolkitEncoderUIFigure  matlab.ui.Figure
        BrowseButton                   matlab.ui.control.Button
        FileEditFieldLabel             matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        PasswordEditField              matlab.ui.control.EditField
        TexttoEncodeEditFieldLabel     matlab.ui.control.Label
        TexttoEncodeEditField          matlab.ui.control.EditField
        EncodedtextcannotincludeLabel  matlab.ui.control.Label
        MaxTextLengthEditFieldLabel    matlab.ui.control.Label
        MaxTextLengthEditField         matlab.ui.control.EditField
        CurrentTextLengthLabel         matlab.ui.control.Label
        CurrentTextLengthEditField     matlab.ui.control.EditField
        EncodeButton                   matlab.ui.control.Button
        OutputFilenameEditFieldLabel   matlab.ui.control.Label
        OutputFilenameEditField        matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.FileEditField.Value = uigetfile({'*.jpg;*.png'})            
            imageSrc = imread(app.FileEditField.Value);
            tempL = imageSrc(:,:,1);
            numElMeows = numel(tempL);
            charLimit = floor(numElMeows/3);
            app.MaxTextLengthEditField.Value = [num2str(numElMeows),' characters'];
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            value = app.FileEditField.Value;            
        end

        % Value changed function: TexttoEncodeEditField
        function TexttoEncodeEditFieldValueChanged(app, event)
            value = app.TexttoEncodeEditField.Value;
            app.CurrentTextLengthEditField.Value = [num2str(length(app.TexttoEncodeEditField.Value)),' characters'];
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            if (app.PasswordEditField.Value=="9001")
            coverImg = imread(app.FileEditField.Value);
            message = app.TexttoEncodeEditField.Value;
            stenoImg = encode(message,coverImg);
            imwrite(stenoImg, [app.OutputFilenameEditField.Value,'.png'],'png');
            confBox = msgbox('Encoded successfully.')
            else
                erBox = msgbox('Invalid Password','Authorization Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StegonographyToolkitEncoderUIFigure = uifigure('Visible', 'off');
            app.StegonographyToolkitEncoderUIFigure.Name = 'Stegonography Toolkit: Encoder';
            app.BrowseButton = uibutton(app.StegonographyToolkitEncoderUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileEditFieldLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.StegonographyToolkitEncoderUIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Tag = 'select1';
            app.FileEditField.Editable = 'off';
            app.PasswordEditFieldLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.StegonographyToolkitEncoderUIFigure, 'text');
            app.PasswordEditField.Tag = 'pass1';
            app.TexttoEncodeEditFieldLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.TexttoEncodeEditFieldLabel.Text = 'Text to Encode';
            app.TexttoEncodeEditField = uieditfield(app.StegonographyToolkitEncoderUIFigure, 'text');
            app.TexttoEncodeEditField.ValueChangedFcn = createCallbackFcn(app, @TexttoEncodeEditFieldValueChanged, true);
            app.TexttoEncodeEditField.Tag = 'enc1';
            app.EncodedtextcannotincludeLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.EncodedtextcannotincludeLabel.Text = 'Encoded text can not include @.';
            app.MaxTextLengthEditFieldLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.MaxTextLengthEditFieldLabel.Text = 'Max Text Length';
            app.MaxTextLengthEditField = uieditfield(app.StegonographyToolkitEncoderUIFigure, 'text');
            app.MaxTextLengthEditField.Editable = 'off';
            app.MaxTextLengthEditField.Value = '0 characters';
            app.CurrentTextLengthLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.CurrentTextLengthLabel.Text = 'Current Text Length';
            app.CurrentTextLengthEditField = uieditfield(app.StegonographyToolkitEncoderUIFigure, 'text');
            app.CurrentTextLengthEditField.Editable = 'off';
            app.CurrentTextLengthEditField.Value = '0 characters';
            app.EncodeButton = uibutton(app.StegonographyToolkitEncoderUIFigure, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Tooltip = {'Encode Password: 9001'};
            app.EncodeButton.Text = 'Encode';
            app.OutputFilenameEditFieldLabel = uilabel(app.StegonographyToolkitEncoderUIFigure);
            app.OutputFilenameEditFieldLabel.Text = 'Output Filename';
            app.OutputFilenameEditField = uieditfield(app.StegonographyToolkitEncoderUIFigure, 'text');
            app.OutputFilenameEditField.Tag = 'out1';
            app.OutputFilenameEditField.Value = 'output';
            % Show the figure after all components are created
            app.StegonographyToolkitEncoderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StegonographyToolkitEncoderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StegonographyToolkitEncoderUIFigure)
        end
    end
end